classdef ms_app_paksi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout6              matlab.ui.container.GridLayout
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        GridLayout5              matlab.ui.container.GridLayout
        SzimulciButton           matlab.ui.control.Button
        ModellSettings           matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        ModellType               matlab.ui.control.DropDown
        LabelsGrid               matlab.ui.container.GridLayout
        TypeSpecificOptionsGrid  matlab.ui.container.GridLayout
        FigureGrid               matlab.ui.container.GridLayout
        EquationLabel            matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        editF, params 
        duration = 10;
        initialValue = 1;
        fields
        settingsFields
        Property6 % Description
        SettingsBoxApp 
        numberOfAxes %delete it later
        t
        y
        map
        settings
        tooltip
    end

    methods (Access = public)

    end
    
    methods (Access = private)

        function makePlot(app)
            if ~isempty(app.t) || ~isempty(app.map)

                if str2double(app.settings.numberOfAxes) == 3;
                    view(app.UIAxes,[5 2 5]);
                    plot3(app.UIAxes,app.t,app.y(:,1),app.y(:,2));
                else
                    cla(app.UIAxes,'reset');
                    app.ModellType.Value
                    switch app.ModellType.Value
                        case 'Folytonos modell (DESS)'
                            plot(app.UIAxes,app.t,app.y);
                            app.UIAxes.FontSize = 24;
                            app.UIAxes.XLabel.String = "t";
                            app.UIAxes.YLabel.String = "s(t)";
                            app.y
                        case 'Diszkrét modell (DTSS)'
                            plot(app.UIAxes,app.t,app.y,".",'MarkerSize',20);
                            app.UIAxes.FontSize = 24;
                            app.UIAxes.XLabel.String = "t";
                            app.UIAxes.YLabel.String = "s(t)";
                            app.UIAxes.XGrid = 'on';
                            app.UIAxes.YGrid = 'on';
                        case 'Diszkrét térbeli modell (CA)'
                            clims = [0 1];
                            for i=1:app.params.duration
                                imagesc(app.UIAxes,app.map(:,:,i),clims)
                                colormap(app.UIAxes,"gray")
                                pause(0.1)
                            end
                            
                        otherwise
                            plot(app.UIAxes,app.t,app.y);
                    end
                    searchedValues = "initialValue";
                    searchedValues(2) = "initialValue1";
                    searchedValues(3) = "initialValue2";
                    searchedValues(4) = "initialValue3";
                    searchedValues(5) = "initialValue4";
                    searchedValues(6) = "initialValue5";
                    fieldValues = string.empty();
                    for i= 1:length(app.fields)
                        for j = 1: length(searchedValues)
                            if app.fields(i).getVariableName() == searchedValues(j)
                                fieldValues(end+1) = app.fields(i).getLegend();
                            end
                        end
                    end
                    legend(app.UIAxes,fieldValues)
                end
            else
                cla(app.UIAxes);
            end
        end
        
        function result = getIdByParam(app,param)
            i = 1;
            result = 0;
            while length(app.fields) >= i
                if app.fields(i).getVariableName() == param
                    result = i;
                end
                i = i + 1;
            end
        end

        function assignParameters(app)
            if app.getIdByParam("duration") ~= 0  app.params.duration = str2double(app.fields(app.getIdByParam("duration")).getFieldValue()); else  app.params.duration = 0; end
            if app.getIdByParam("initialValue") ~= 0  app.params.initialValue = str2double(app.fields(app.getIdByParam("initialValue")).getFieldValue()); else  app.params.initialValue = 0; end
            if app.getIdByParam("initialValue1") ~= 0  app.params.initialValues(1) = str2double(app.fields(app.getIdByParam("initialValue1")).getFieldValue()); else  app.params.initialValues(1) = 0; end
            if app.getIdByParam("initialValue2") ~= 0  app.params.initialValues(2) = str2double(app.fields(app.getIdByParam("initialValue2")).getFieldValue()); else  app.params.initialValues(2) = 0; end
            if app.getIdByParam("initialValue3") ~= 0  app.params.initialValues(3) = str2double(app.fields(app.getIdByParam("initialValue3")).getFieldValue()); else  app.params.initialValues(3) = 0; end
            if app.getIdByParam("initialValue4") ~= 0  app.params.initialValues(4) = str2double(app.fields(app.getIdByParam("initialValue4")).getFieldValue()); else  app.params.initialValues(4) = 0; end
            if app.getIdByParam("initialValue5") ~= 0  app.params.initialValues(5) = str2double(app.fields(app.getIdByParam("initialValue5")).getFieldValue()); else  app.params.initialValues(5) = 0; end
            if app.getIdByParam("r") ~= 0  app.params.r = str2double(app.fields(app.getIdByParam("r")).getFieldValue()); else  app.params.r = 0; end
            if app.getIdByParam("r1") ~= 0  app.params.r1 = str2double(app.fields(app.getIdByParam("r1")).getFieldValue()); else  app.params.r1 = 0; end
            if app.getIdByParam("r2") ~= 0  app.params.r2 = str2double(app.fields(app.getIdByParam("r2")).getFieldValue()); else  app.params.r2 = 0; end
            if app.getIdByParam("K") ~= 0  app.params.K = str2double(app.fields(app.getIdByParam("K")).getFieldValue()); else  app.params.K = 0; end
            if app.getIdByParam("K1") ~= 0  app.params.K1 = str2double(app.fields(app.getIdByParam("K1")).getFieldValue()); else  app.params.K1 = 0; end
            if app.getIdByParam("K2") ~= 0  app.params.K2 = str2double(app.fields(app.getIdByParam("K2")).getFieldValue()); else  app.params.K2 = 0; end
            if app.getIdByParam("alpha") ~= 0  app.params.alpha = str2double(app.fields(app.getIdByParam("alpha")).getFieldValue()); else  app.params.alpha = 0; end
            if app.getIdByParam("beta") ~= 0  app.params.beta = str2double(app.fields(app.getIdByParam("beta")).getFieldValue()); else  app.params.beta = 0; end
            if app.getIdByParam("beta1") ~= 0  app.params.beta1 = str2double(app.fields(app.getIdByParam("beta1")).getFieldValue()); else  app.params.beta1 = 0; end
            if app.getIdByParam("beta2") ~= 0  app.params.beta2 = str2double(app.fields(app.getIdByParam("beta2")).getFieldValue()); else  app.params.beta2 = 0; end
            if app.getIdByParam("betaI") ~= 0  app.params.betaI = str2double(app.fields(app.getIdByParam("betaI")).getFieldValue()); else  app.params.betaI = 0; end
            if app.getIdByParam("betaA") ~= 0  app.params.betaA = str2double(app.fields(app.getIdByParam("betaA")).getFieldValue()); else  app.params.betaA = 0; end
            if app.getIdByParam("delta") ~= 0  app.params.delta = str2double(app.fields(app.getIdByParam("delta")).getFieldValue()); else  app.params.delta = 0; end
            if app.getIdByParam("gamma") ~= 0  app.params.gamma = str2double(app.fields(app.getIdByParam("gamma")).getFieldValue()); else  app.params.gamma = 0; end
            if app.getIdByParam("gamma1") ~= 0  app.params.gamma1 = str2double(app.fields(app.getIdByParam("gamma1")).getFieldValue()); else  app.params.gamma1 = 0; end
            if app.getIdByParam("gamma2") ~= 0  app.params.gamma2 = str2double(app.fields(app.getIdByParam("gamma2")).getFieldValue()); else  app.params.gamma2 = 0; end
            if app.getIdByParam("sigma") ~= 0  app.params.sigma = str2double(app.fields(app.getIdByParam("sigma")).getFieldValue()); else  app.params.sigma = 0; end
            if app.getIdByParam("p") ~= 0  app.params.p = str2double(app.fields(app.getIdByParam("p")).getFieldValue()); else  app.params.p = 0; end
            if app.getIdByParam("dA") ~= 0  app.params.dA = str2double(app.fields(app.getIdByParam("dA")).getFieldValue()); else  app.params.dA = 0; end
            if app.getIdByParam("dI") ~= 0  app.params.dI = str2double(app.fields(app.getIdByParam("dI")).getFieldValue()); else  app.params.dI = 0; end
            if app.getIdByParam("p") ~= 0  app.params.p = str2double(app.fields(app.getIdByParam("p")).getFieldValue()); else  app.params.p = 0; end
            if app.getIdByParam("h") ~= 0  app.params.h = str2double(app.fields(app.getIdByParam("h")).getFieldValue()); else  app.params.h = 0; end
            if app.getIdByParam("mu") ~= 0  app.params.mu = str2double(app.fields(app.getIdByParam("mu")).getFieldValue()); else  app.params.mu = 0; end
            if app.getIdByParam("aliveCells") ~= 0  app.params.aliveCells = app.fields(app.getIdByParam("aliveCells")).getFieldValue(); else  app.params.aliveCells = ""; end
            if app.getIdByParam("bRule") ~= 0  app.params.bRule = app.fields(app.getIdByParam("bRule")).getFieldValue(); else  app.params.bRule = ""; end
            if app.getIdByParam("sRule") ~= 0  app.params.sRule = app.fields(app.getIdByParam("sRule")).getFieldValue(); else  app.params.sRule = ""; end
            if app.getIdByParam("x") ~= 0  app.params.x = str2double(app.fields(app.getIdByParam("x")).getFieldValue()); else  app.params.mu = 10; end
            if app.getIdByParam("y") ~= 0  app.params.y = str2double(app.fields(app.getIdByParam("y")).getFieldValue()); else  app.params.mu = 10; end
            if app.getIdByParam("rule") ~= 0  app.params.rule = str2double(app.fields(app.getIdByParam("rule")).getFieldValue()); else  app.params.rule = 126; end
        end
        
        
        function result = getSettingsFieldByName(app,name)
            for i = 1: length(app.settingsFields)
                if app.settingsFields(i).getVariableName() == name
                    result = app.settingsFields(i);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.settings.numberOfAxes = 2;
            app.tooltip.duration = "Szimuláció időtartama";
            app.tooltip.initValue = "Kezdeti érték";
            app.tooltip.initValue1 = "Első pupuláció kezdeti értéke";
            app.tooltip.initValue2 = "Második pupuláció kezdeti értéke";
            app.tooltip.r1 = 'Első populáció növeketési rátája';
            app.tooltip.r2 = 'Második populáció növeketési rátája';
            app.tooltip.K1 = 'Első populáció kapacitása';
            app.tooltip.K2 = 'Második populáció kapacitása';
            app.tooltip.S = 'Kezdeti fogékonyak száma';
            app.tooltip.E = 'Kezdeti fertőzött de nem fertőzők száma';
            app.tooltip.A = 'Kezdeti fertőzöttek (tünetmentes) száma';
            app.tooltip.I = 'Kezdeti fertőzött száma';
            app.tooltip.R = 'Kezdeti felépültek száma';
            app.tooltip.D = 'Kezdeti elhúnytak száma';
        end

        % Button pushed function: SzimulciButton
        function displayModel(app, event)
            plotted = true;
            switch app.ModellType.Value
                case "Folytonos modell (DESS)"
                    switch app.getSettingsFieldByName("sSolver").field.Value
                        case "ode15s"
                            solveODE = SelectSolver(@ode15s,app.params);
                        case "ode45"
                            solveODE = SelectSolver(@ode45,app.params);
                    end
                    switch app.getSettingsFieldByName("sModell").field.Value
                        case "Exponenciális"
                            app.assignParameters();
                            [app.t app.y] = solveODE.expFun(app.params);
                        case "Logisztikus"
                            app.assignParameters();
                            [app.t app.y] = solveODE.logFun(app.params);
                        case "Logisztikus redukcióval"
                            app.assignParameters();
                            [app.t app.y] = solveODE.logRedFun(app.params);
                        case "Neutralizmus"
                            app.assignParameters();
                            [app.t app.y] = solveODE.neutFun(app.params);
                        case "Szimbiózis (exp)"
                            app.assignParameters();
                            [app.t app.y] = solveODE.symbExpFun(app.params);
                        case "Szimbiózis (log)"
                            app.assignParameters();
                            [app.t app.y] = solveODE.symbLogFun(app.params);
                        case "Kommenzalizmus és amenzalizmus"
                            app.assignParameters();
                            [app.t app.y] = solveODE.commFun(app.params);
                        case "Kompetíció"
                            app.assignParameters();
                            [app.t app.y] = solveODE.compFun(app.params);
                        case "Zsákmány-ragadozó"
                            app.assignParameters();
                            [app.t app.y] = solveODE.predFun(app.params);
                         case "Zsákmány-ragadozó (korlátozott ragadozó)"
                            app.assignParameters();
                            [app.t app.y] = solveODE.predRedFun(app.params);
        
                        case "SIR"
                            app.assignParameters();
                            [app.t app.y] = solveODE.sirFun(app.params);
                        case "SIS"
                            app.assignParameters();
                            [app.t app.y] = solveODE.sisFun(app.params);
                        case "SIRD"
                            app.assignParameters();
                            [app.t app.y] = solveODE.sirdFun(app.params);
                        case "SIAR"
                            app.assignParameters();
                            [app.t app.y] = solveODE.siarFun(app.params);
                        case "SEAIR"
                            app.assignParameters();
                            [app.t app.y] = solveODE.seairFun(app.params);
                        otherwise
                            plotted = false;
                    end
                case "Diszkrét modell (DTSS)"
                    app.assignParameters();
                    solve = SolveDTSS(app.params,str2double(app.getSettingsFieldByName("sStep").field.Value));
                    switch app.getSettingsFieldByName("sModell").field.Value
                        case "Exponenciális"
                            [app.t app.y] = solve.expFun();
                        case "Logisztikus"
                            [app.t app.y] = solve.logFun();
                        case "Horgászati"
                            [app.t app.y] = solve.harvFun();

                        case "Fibonacci számsor"
                            [app.t app.y] = solve.fiboFun();
                        case "Catalan számsor"
                            [app.t app.y] = solve.catalFun();

                        case "Kölcsön törtleszés"
                            [app.t app.y] = solve.kolcsFun();
                    end
                case "Diszkrét térbeli modell (CA)"
                    app.assignParameters();
                    solve = SolveCA(app.params);
                     switch app.getSettingsFieldByName("sModell").field.Value
                        case "Wolfram sejtautómaták"
                            app.map = solve.wolframFun();
                        case "Life-like sejtautómaták"
                            app.map = solve.lifeLikeFun();
                     end
                end
            app.makePlot()
        end

        % Callback function
        function modellSelected(app, event)
            for i = 1:length(app.fields)
               app.fields(i).delete();
            end

            switch app.ModellType.Value
                case "Folytonos modell (DESS)"
                    switch app.getSettingsFieldByName("sModell").field.Value
                        case "Exponenciális"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_0$",app.tooltip.initValue,"initialValue","1",2,"Populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$r$","Növeketési ráta","r","1",3);
                            app.EquationLabel.Text = "$\dot{s}=rs$";
                        case "Logisztikus"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_0$",app.tooltip.initValue,"initialValue","1",2,"Populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$r$","Növeketési ráta","r","1",3);
                            app.fields(4) = CustomField(app,"$K$","Kapacitás","K","10",4);
                            app.EquationLabel.Text = "$\dot{s}=rs\left(1-\frac{s}{K}\right)$";
                        case "Logisztikus redukcióval"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_0$",app.tooltip.initValue,"initialValue","1",2,"Populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$r$","Növeketési ráta","r","1",3);
                            app.fields(4) = CustomField(app,"$K$","Kapacitás","K","10",4);
                            app.fields(5) = CustomField(app,"$h$","regulációs tényező","h","-0.1",5);
                            app.EquationLabel.Text = "$\dot{s}=rs\left(1-\frac{s}{K}\right)-hs$";
                        case "Neutralizmus"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_1(t_0)$",app.tooltip.initValue1,"initialValue1","1",2,"Első populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$s_2(t_0)$",app.tooltip.initValue2,"initialValue2","1",3,"Második populáció egyedszáma");
                            app.fields(4) = CustomField(app,"$r_1$",app.tooltip.r1,"r1","0.3",4);
                            app.fields(5) = CustomField(app,"$r_2$",app.tooltip.r2,"r2","1",5);
                            app.fields(6) = CustomField(app,"$K_2$",app.tooltip.K2,"K2","10",6);
                            app.EquationLabel.Text = "${\dot{s}}_1=r_1 s_1 \\ {\dot{s}}_2=r_2 s_2(1-\frac{s_2}{K_2})$";
                        case "Szimbiózis (exp)"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_1(t_0)$",app.tooltip.initValue1,"initialValue1","1",2,"Első populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$s_2(t_0)$",app.tooltip.initValue2,"initialValue2","1",3,"Második populáció egyedszáma");
                            app.fields(4) = CustomField(app,"$r_1$",app.tooltip.r1,"r1","0.3",4);
                            app.fields(5) = CustomField(app,"$r_2$",app.tooltip.r2,"r2","0.1",5);
                            app.fields(6) = CustomField(app,"$\alpha$","A második populáció elsőre gyakorolt hatása","alpha","0.03",6);
                            app.fields(7) = CustomField(app,"$\beta$","Az első populáció másodikra gyakorolt hatása","beta","0.02",7);
                            app.EquationLabel.Text = "${\dot{s}}_1=r_1s_1+\alpha s_1s_2 \\ {\dot{s}}_2=r_2s_2+\beta s_1s_2$";
                        case "Szimbiózis (log)"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_1(t_0)$",app.tooltip.initValue1,"initialValue1","1",2,"Első populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$s_2(t_0)$",app.tooltip.initValue2,"initialValue2","1",3,"Második populáció egyedszáma");
                            app.fields(4) = CustomField(app,"$r_1$",app.tooltip.r1,"r1","1",4);
                            app.fields(5) = CustomField(app,"$r_2$",app.tooltip.r2,"r2","0.5",5);
                            app.fields(6) = CustomField(app,"$K_1$",app.tooltip.K1,"K1","10",6);
                            app.fields(7) = CustomField(app,"$K_2$",app.tooltip.K2,"K2","10",7);
                            app.fields(8) = CustomField(app,"$\alpha$","A második populáció elsőre gyakorolt hatása","alpha","0.01",8);
                            app.fields(9) = CustomField(app,"$\beta$","Az első populáció másodikra gyakorolt hatása","beta","0.03",9);
                            app.EquationLabel.Text = "${{\dot{s}}_1=r_1s_1\frac{K_1-s_1+\alpha s_2}{K_1} \\ {\dot{s}}_2=r_2s_2\frac{K_2-s_2+\beta s_1}{K_2}$";
                        case "Kommenzalizmus és amenzalizmus"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_1(t_0)$",app.tooltip.initValue1,"initialValue1","1",2,"Első populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$s_2(t_0)$",app.tooltip.initValue2,"initialValue2","1",3,"Második populáció egyedszáma");
                            app.fields(4) = CustomField(app,"$r_1$",app.tooltip.r1,"r1","1",4);
                            app.fields(5) = CustomField(app,"$r_2$",app.tooltip.r2,"r2","0.5",5);
                            app.fields(6) = CustomField(app,"$K_1$",app.tooltip.K1,"K1","10",6);
                            app.fields(7) = CustomField(app,"$K_2$",app.tooltip.K2,"K2","10",7);
                            app.fields(8) = CustomField(app,"$\alpha$","A második populáció elsőre gyakorolt hatása","alpha","0.01",8);
                            app.EquationLabel.Text = "${\dot{s}}_1=r_1s_1\frac{K_1-s_1+\alpha s_2}{K_1} \\ {\dot{s}}_2=r_2s_2\frac{K_2-s_2}{K_2}$";
                        case "Kompetíció"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_1(t_0)$",app.tooltip.initValue1,"initialValue1","1",2,"Első populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$s_2(t_0)$",app.tooltip.initValue2,"initialValue2","1",3,"Második populáció egyedszáma");
                            app.fields(4) = CustomField(app,"$r_1$",app.tooltip.r1,"r1","1",4);
                            app.fields(5) = CustomField(app,"$r_2$",app.tooltip.r2,"r2","0.5",5);
                            app.fields(6) = CustomField(app,"$K_1$",app.tooltip.K1,"K1","10",6);
                            app.fields(7) = CustomField(app,"$K_2$",app.tooltip.K2,"K2","10",7);
                            app.fields(8) = CustomField(app,"$\alpha$","A második populáció elsőre gyakorolt hatása","alpha","0.01",8);
                            app.fields(9) = CustomField(app,"$\beta$","Az első populáció másodikra gyakorolt hatása","beta","0.03",9);
                            app.EquationLabel.Text = "${\dot{s}}_1=r_1s_1\frac{K_1-s_1-\alpha s_2}{K_1} \\ {\dot{s}}_2=r_2s_2\frac{K_2-s_2-\beta s_1}{K_2}$";
                        case "Zsákmány-ragadozó"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_1(t_0)$","Zsákmány populáció kezdeti értéke","initialValue1","2",2,"Zsákmány populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$s_2(t_0)$","Ragadozó populáció kezdeti értéke","initialValue2","1",3,"Ragadozó populáció egyedszáma");
                            app.fields(4) = CustomField(app,"$r_1$","Zsákmány populáció növeketési rátája","r1","1",4);
                            app.fields(5) = CustomField(app,"$r_2$","Zsákmány hasznosításának mértéke","r2","1",5);
                            app.fields(6) = CustomField(app,"$\alpha$","A ragadozó populáció zsákmányra gyakorolt hatása","alpha","1",6);
                            app.fields(7) = CustomField(app,"$\beta$","Ragadozók halálozási rátája","beta","1",7);
                            app.EquationLabel.Text = "${\dot{s}}_1=r_1s_1-{\alpha s}_1s_2 \\ {\dot{s}}_2=r_2\alpha s_1s_2-\beta s_2$";
                        case "Zsákmány-ragadozó (korlátozott ragadozó)"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","20",1);
                            app.fields(2) = CustomField(app,"$s_1(t_0)$","Zsákmány populáció kezdeti értéke","initialValue1","2",2,"Zsákmány populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$s_2(t_0)$","Ragadozó populáció kezdeti értéke","initialValue2","1",3,"Ragadozó populáció egyedszáma");
                            app.fields(4) = CustomField(app,"$r_1$","Zsákmány populáció növeketési rátája","r1","1",4);
                            app.fields(5) = CustomField(app,"$r_2$","Zsákmány hasznosításának mértéke","r2","1",5);
                            app.fields(6) = CustomField(app,"$\alpha$","A ragadozó populáció zsákmányra gyakorolt hatása","alpha","1",6);
                            app.fields(7) = CustomField(app,"$\beta$","Ragadozók halálozási rátája","beta","1",7);
                            app.fields(8) = CustomField(app,"$K_1$","Zsákmány populáció kapacitása","K1","1.5",8);
                            app.EquationLabel.Text = "${\dot{s}}_1=r_1s_1(1-\frac{s_1}{K_1})-{\alpha s}_1s_2 \\ {\dot{s}}_2=r_2\alpha s_1s_2-\beta s_2$";
        

                        case "SIR"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$S_0$",app.tooltip.S ,"initialValue1","100",2,"Fogékonyak száma (S)");
                            app.fields(3) = CustomField(app,"$I_0$",app.tooltip.I,"initialValue2","1",3,"Fertőzött száma (I)");
                            app.fields(4) = CustomField(app,"$R_0$",app.tooltip.R,"initialValue3","0",4,"Felépültek száma (R)");
                            app.fields(5) = CustomField(app,"$\beta$","beta","beta","0.03",5);
                            app.fields(6) = CustomField(app,"$\gamma$","gamma","gamma","0.5",6);
                            app.EquationLabel.Text = "${\dot{S}} =-\beta S I  \\ {\dot{I}} =\beta S I -\gamma I \\ {\dot{R}} =\gamma I$$";
                         case "SIS"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","100",1);
                            app.fields(2) = CustomField(app,"$S_0$",app.tooltip.S ,"initialValue1","10",2,"Fogékonyak száma (S)");
                            app.fields(3) = CustomField(app,"$I_0$",app.tooltip.I,"initialValue2","1",3,"Fertőzött száma (I)");
                            app.fields(4) = CustomField(app,"$\beta$","Megbetegedők aránya","beta","0.9",4);
                            app.fields(5) = CustomField(app,"$\gamma$","Felépülők aránya","gamma","0.4",5);
                            app.EquationLabel.Text = "$ \dot{S}=-\frac{\beta S I}{N}+\gamma I \\  \dot{I}=\frac{\beta S I}{N}-\gamma I$";
                         case "SIRD"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","100",1);
                            app.fields(2) = CustomField(app,"$S_0$",app.tooltip.S ,"initialValue1","10",2,"Fogékonyak száma (S)");
                            app.fields(3) = CustomField(app,"$I_0$",app.tooltip.I,"initialValue2","1",3,"Fertőzött száma (I)");
                            app.fields(4) = CustomField(app,"$R_0$",app.tooltip.R,"initialValue3","0",4,"Felépültek száma (R)");
                            app.fields(5) = CustomField(app,"$D_0$",app.tooltip.D,"initialValue4","0",5,"Elhúnytak száma (D)");
                            app.fields(6) = CustomField(app,"$\beta$","Megbetegedők aránya","beta","0.9",6);
                            app.fields(7) = CustomField(app,"$\gamma$","Felépülők aránya","gamma","0.6",7);
                            app.fields(8) = CustomField(app,"$\mu$","Elhalálozási arány","mu","0.4",8);
                            app.EquationLabel.Text = "${\dot{S}} =-\frac{\beta S I}{N}  \\ {\dot{I}} =\frac{\beta S I}{N} -\gamma I - \mu I \\ {\dot{R}} =\gamma I \\ {\dot{D}} =\mu I$$";
                        case "SIAR"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$S_0$",app.tooltip.S ,"initialValue1","100",2,"Fogékonyak száma (S)");
                            app.fields(3) = CustomField(app,"$I_0$",app.tooltip.I,"initialValue2","1",3,"Fertőzött száma (I)");
                            app.fields(4) = CustomField(app,"$A_0$",app.tooltip.A,"initialValue3","3",4,"Fertőzöttek (tünetmentes) száma (A)");
                            app.fields(5) = CustomField(app,"$R_0$",app.tooltip.R,"initialValue4","0",5,"Felépültek száma (R)");
                            app.fields(6) = CustomField(app,"$\beta_1$","beta1","beta1","0.03",6);
                            app.fields(7) = CustomField(app,"$\beta_2$","beta2","beta2","0.01",7);
                            app.fields(8) = CustomField(app,"$\gamma_1$","gamma1","gamma1","0.1",8);
                            app.fields(9) = CustomField(app,"$\gamma_2$","gamma2","gamma2","0.5",9);
                            app.fields(10) = CustomField(app,"$p$","p","p","0.5",10);
                            app.EquationLabel.Text = "${\dot{S}} =-\beta_1 S I-\beta_2 S A \\ {\dot{I}} =p(\beta_1 S I+\beta_2 S A)-\gamma_1 I \\ {\dot{A}} =(1-p)(\beta_1 S I+\beta_2 S A)-\gamma_2 A \\ {\dot{R}} =\gamma_1 I+\gamma_2 A$";
                        case "SEAIR"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$S_0$",app.tooltip.S ,"initialValue1","100",2,"Fogékonyak száma (S)");
                            app.fields(3) = CustomField(app,"$E_0$",app.tooltip.E,"initialValue2","3",3,"Fertőzött de nem fertőzők száma (E)");
                            app.fields(4) = CustomField(app,"$A_0$",app.tooltip.A,"initialValue3","0",4,"Fertőzöttek (tünetmentes) száma (A)");
                            app.fields(5) = CustomField(app,"$I_0$",app.tooltip.I,"initialValue4","0",5,"Fertőzött száma (I)");
                            app.fields(6) = CustomField(app,"$R_0$",app.tooltip.R,"initialValue5","0",6,"Felépültek száma (R)");
                            app.fields(7) = CustomField(app,"$\beta_I$","betaI","betaI","1",7);
                            app.fields(8) = CustomField(app,"$\beta_A$","betaA","betaA","0.5",8);
                            app.fields(9) = CustomField(app,"$d_A$","dA","dA","0.5",9);
                            app.fields(10) = CustomField(app,"$d_I$","dI","dI","0.5",10);
                            app.fields(11) = CustomField(app,"$\alpha$","alpha","alpha","0.5",11);
                            app.fields(12) = CustomField(app,"$\delta$","delta","delta","0.5",12);
                            app.fields(13) = CustomField(app,"$\sigma$","sigma","sigma","0.5",13);
                            app.EquationLabel.Text = "$ {\dot{S}}=-\beta_I I S-\beta_A A S+\delta R \\ {\dot{E}}=+\beta_I I S+\beta_A A S-\sigma E \\ {\dot{A}}=\alpha \sigma E-d_A A \\ {\dot{I}}=(1-\alpha) \sigma E-d_I I \\ {\dot{R}}=d_A A+d_I I-\delta R$";
                    end
                case "Diszkrét modell (DTSS)"
                    switch app.getSettingsFieldByName("sModell").field.Value
                        case "Exponenciális"
                            app.fields = CustomField(app,"$t(max)$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s(0)$",app.tooltip.initValue,"initialValue","1",2,"Populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$r$","Növeketési ráta","r","1",3);
                            app.EquationLabel.Text = "$s(t)=s(t-1)+s(t-1)r$";

                        case "Logisztikus"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_0$",app.tooltip.initValue,"initialValue","1",2,"Populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$r$","Növeketési ráta","r","1",3);
                            app.fields(4) = CustomField(app,"$K$","Kapacitás","K","10",4);
                            app.EquationLabel.Text = "$s(t+1)=s(t)+r s(t)\left(1-\frac{s(t)}{K}\right)$";

                        case "Horgászati"
                            app.fields = CustomField(app,"$t_{max}$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s_0$",app.tooltip.initValue,"initialValue","1",2,"Populáció egyedszáma");
                            app.fields(3) = CustomField(app,"$r$","Növeketési ráta","r","1",3);
                            app.fields(4) = CustomField(app,"$K$","Kapacitás","K","10",4);
                            app.fields(5) = CustomField(app,"$h$","Regulációs tényező","h","0.5",5);
                            app.EquationLabel.Text = "$s(t+1)=s(t)+r s(t)\left(1-\frac{s(t)}{K}\right)-h$";

                        case "Fibonacci számsor"
                            app.fields = CustomField(app,"$t(max)$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s(1)$","Számsor első eleme" ,"initialValue1","0",2,"Fibonacci számok");
                            app.fields(3) = CustomField(app,"$s(2)$","Számsor második eleme","initialValue2","1",3,"Fibonacci számok");
                            app.EquationLabel.Text = "$s(t)=s(t-1)+s(t-2)$";

                        case "Catalan számsor"
                            app.fields = CustomField(app,"$t(max)$",app.tooltip.duration,"duration","10",1);
                            app.fields(2) = CustomField(app,"$s(1)$","Számsor első eleme" ,"initialValue","1",2,"Catalan számok");
                            app.EquationLabel.Text = "$x(t+1)=\frac{2(t+1)}{t+2}$";

                        case "Kölcsön törtleszés"
                            app.fields = CustomField(app,"$t(max)$",app.tooltip.duration,"duration","120",1);
                            app.fields(2) = CustomField(app,"$s(1)$","Felvett hitel összege" ,"initialValue","50000",2,"Fennmaradó hitel");
                            app.fields(3) = CustomField(app,"$r$","Éves kamatláb" ,"r","0.001",3);
                            app.fields(4) = CustomField(app,"$p$","Havi törlesztőrészlet" ,"p","300",4);
                            app.EquationLabel.Text = "$b(t)=rb(t-1)-p(t)$";
                    end
                case "Diszkrét térbeli modell (CA)"
                    switch app.getSettingsFieldByName("sModell").field.Value % 
                        case "Wolfram sejtautómaták"
                            app.fields = CustomField(app,"$t(max)$",app.tooltip.duration,"duration","30",1);
                            app.fields(2) = CustomField(app,"$x$","Sejttér","x","50",2);
                            app.fields(3) = CustomField(app,"$A(0)$","Élő cellák | Ha üresen hagyja akkor véletlenszerű" ,"aliveCells","25,27,31",3);
                            app.fields(4) = CustomField(app,"$b$","Születési szabályok" ,"rule","126",4);
                            app.EquationLabel.Text = "";

                        case "Life-like sejtautómaták"
                            app.fields = CustomField(app,"$t(max)$",app.tooltip.duration,"duration","30",1);
                            app.fields(2) = CustomField(app,"$x$","Sejttér szélessége","x","100",2);
                            app.fields(3) = CustomField(app,"$y$","Sejttér magassága","y","100",3);
                            app.fields(4) = CustomField(app,"$A(0)$","Élő cellák | Ha üresen hagyja akkor véletlenszerű" ,"aliveCells","(5,5) (5,6) (6,5) (7,6) (7,8) (3,13) (12,17) (20,20) (20,21) (21,20) (21,21) (40,40) (40,41) (40,42) (39,42) (38,41) (80,20) (80,21) (80,22)",4);
                            app.fields(5) = CustomField(app,"$b$","Születési szabályok" ,"bRule","3",5);
                            app.fields(6) = CustomField(app,"$s$","Túlélési szabályok" ,"sRule","23",6);
                            app.EquationLabel.Text = "";
                    end
            end
            cla(app.UIAxes)

            app.tooltip.S = 'Kezdeti fogékonyak száma';
            app.tooltip.E = 'Kezdeti fertőzött de nem fertőzők száma';
            app.tooltip.A = 'Kezdeti fertőzöttek (tünetmentes) száma';
            app.tooltip.I = 'Kezdeti fertőzött száma';
            app.tooltip.R = 'Kezdeti felépültek száma';


        end

        % Callback function
        function categoryChange(app, event)
            selectedCategory = app.getSettingsFieldByName("sCategory").getFieldValue();
            app.EquationLabel.Text = "";
            switch app.ModellType.Value
                case "Folytonos modell (DESS)"
                    switch selectedCategory
                        case "Válasszon"
                            app.getSettingsFieldByName("sModell").field.Items = ["Válasszon"];
                            app.modellSelected();
                        case "Populációs modellek"
                            app.getSettingsFieldByName("sModell").field.Items = [
                                "Válasszon"
                                "Exponenciális"
                                "Logisztikus"
                                "Logisztikus redukcióval"
                                "Neutralizmus", 
                                "Szimbiózis (exp)"
                                "Szimbiózis (log)"
                                "Kommenzalizmus és amenzalizmus"
                                "Kompetíció"
                                "Zsákmány-ragadozó"
                                "Zsákmány-ragadozó (korlátozott ragadozó)"
                                ];
                            app.modellSelected();
                        case "Járványterjedési modellek"
                            app.getSettingsFieldByName("sModell").field.Items = [
                                "Válasszon"
                                "SIR"
                                "SIS"
                                "SIRD"
                                "SIAR"
                                "SEAIR"
                                ];
                            app.modellSelected();
                    end 
                case "Diszkrét modell (DTSS)"
                    switch selectedCategory
                        case "Válasszon"
                            app.getSettingsFieldByName("sModell").field.Items = ["Válasszon"];
                            app.modellSelected();
                        case "Gazdasági modellek"
                            app.getSettingsFieldByName("sModell").field.Items = [
                                "Válasszon"
                                "Kölcsön törtleszés"
                                ];
                            app.modellSelected();
                        case "Populációs modellek"
                            app.getSettingsFieldByName("sModell").field.Items = [
                                "Válasszon"
                                "Exponenciális"
                                "Logisztikus"
                                "Horgászati"
                                ];
                            app.modellSelected();
                        case "Matematikai sorok"
                            app.getSettingsFieldByName("sModell").field.Items = [
                                "Fibonacci számsor"
                                "Catalan számsor"
                                ];
                            app.modellSelected();
                    end 
                        
            end
        end

        % Value changed function: ModellType
        function changeModellType(app, event)
            for i = 1:length(app.settingsFields)
               app.settingsFields(i).delete();
            end
            for i = 1:length(app.fields)
               app.fields(i).delete();
            end
            switch app.ModellType.Value
                case "Folytonos modell (DESS)"
                    app.settingsFields = SettingsField(app,"Kategória","sCategory",["Válasszon", "Populációs modellek", "Járványterjedési modellek"],1,@app.categoryChange);
                    app.settingsFields(2) = SettingsField(app,"Modell","sModell",["Válasszon", ""],2,@app.modellSelected);
                    app.settingsFields(3) = SettingsField(app,"Solver","sSolver",["ode45", "ode15s"],3);
                case "Diszkrét modell (DTSS)"
                    app.settingsFields = SettingsField(app,"Kategória","sCategory",["Válasszon", "Populációs modellek", "Gazdasági modellek", "Matematikai sorok"],1,@app.categoryChange);
                    app.settingsFields(2) = SettingsField(app,"Modell","sModell",["Válasszon", ""],2,@app.modellSelected);
                    app.settingsFields(3) = SettingsField(app,"Lépéshossz","sStep","1",3);
                case "Diszkrét térbeli modell (CA)"
                    app.settingsFields = SettingsField(app,"Modell","sModell",["Válasszon", "Wolfram sejtautómaták", "Life-like sejtautómaták"],1,@app.modellSelected);
                otherwise

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout6 = uigridlayout(app.UIFigure);
            app.GridLayout = uigridlayout(app.GridLayout6);
            app.FigureGrid = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.FigureGrid);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.EquationLabel = uilabel(app.FigureGrid);
            app.EquationLabel.Interpreter = 'latex';
            app.EquationLabel.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ModellSettings = uipanel(app.GridLayout2);
            app.ModellSettings.Title = 'Modell beállításai';
            app.GridLayout3 = uigridlayout(app.ModellSettings);
            app.TypeSpecificOptionsGrid = uigridlayout(app.GridLayout3);
            app.LabelsGrid = uigridlayout(app.GridLayout3);
            app.ModellType = uidropdown(app.GridLayout3);
            app.ModellType.Items = {'Válasszon', 'Folytonos modell (DESS)', 'Diszkrét modell (DTSS)', 'Diszkrét térbeli modell (CA)'};
            app.ModellType.ValueChangedFcn = createCallbackFcn(app, @changeModellType, true);
            app.ModellType.Value = 'Válasszon';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.SzimulciButton = uibutton(app.GridLayout5, 'push');
            app.SzimulciButton.ButtonPushedFcn = createCallbackFcn(app, @displayModel, true);
            app.SzimulciButton.Text = 'Szimuláció';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ms_app_paksi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end